classdef sleep_ana < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadEEGButton              matlab.ui.control.Button
        sLabel                     matlab.ui.control.Label
        fsHzEditFieldLabel         matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        end_view_EditField         matlab.ui.control.NumericEditField
        fsHzEditField              matlab.ui.control.NumericEditField
        viewEditFieldLabel         matlab.ui.control.Label
        start_view_EditField       matlab.ui.control.NumericEditField
        LoadEMGButton              matlab.ui.control.Button
        FullViewButton             matlab.ui.control.Button
        EEGSpectrogramButton       matlab.ui.control.Button
        WinLensEditFieldLabel      matlab.ui.control.Label
        WinLensEditField           matlab.ui.control.NumericEditField
        OverlapsEditFieldLabel     matlab.ui.control.Label
        OverlapsEditField          matlab.ui.control.NumericEditField
        SelROICheckBox             matlab.ui.control.CheckBox
        EpochEditFieldLabel        matlab.ui.control.Label
        EpochEditField             matlab.ui.control.NumericEditField
        sLabel_5                   matlab.ui.control.Label
        AutoScoreButton            matlab.ui.control.Button
        ManualThresholdButton      matlab.ui.control.Button
        nremLabel                  matlab.ui.control.Label
        remLabel                   matlab.ui.control.Label
        wakeLabel                  matlab.ui.control.Label
        StartsEditFieldLabel       matlab.ui.control.Label
        StartsEditField            matlab.ui.control.NumericEditField
        EndsEditFieldLabel         matlab.ui.control.Label
        EndsEditField              matlab.ui.control.NumericEditField
        binsEditFieldLabel         matlab.ui.control.Label
        bins_delta_EditField       matlab.ui.control.NumericEditField
        binsEditField_2Label       matlab.ui.control.Label
        bins_theta_EditField       matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        delta_to_EditField         matlab.ui.control.NumericEditField
        binsEditFieldLabel_2       matlab.ui.control.Label
        bins_EMG_EditField         matlab.ui.control.NumericEditField
        EMG_from_EditField         matlab.ui.control.NumericEditField
        EditField_4Label           matlab.ui.control.Label
        EMG_to_EditField           matlab.ui.control.NumericEditField
        ExportDataButton           matlab.ui.control.Button
        theta_from_EditField       matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        delta_from_EditField       matlab.ui.control.NumericEditField
        theta_to_EditField         matlab.ui.control.NumericEditField
        NarrowDownButton           matlab.ui.control.Button
        SegmentlensEditFieldLabel  matlab.ui.control.Label
        SegmentlensEditField       matlab.ui.control.NumericEditField
        RatioChangeButton          matlab.ui.control.Button
        LoadTriggersButton         matlab.ui.control.Button
        LoadPreprocessedButton     matlab.ui.control.Button
        ResetThetaButton           matlab.ui.control.Button
        ResetDeltaButton           matlab.ui.control.Button
        ResetEMGButton             matlab.ui.control.Button
        EEGUIAxes                  matlab.ui.control.UIAxes
        EMGUIAxes                  matlab.ui.control.UIAxes
        spectrogramUIAxes          matlab.ui.control.UIAxes
        deltaUIAxes                matlab.ui.control.UIAxes
        thetaratioUIAxes           matlab.ui.control.UIAxes
        EMGpowerUIAxes             matlab.ui.control.UIAxes
        DeltaThetaUIAxes           matlab.ui.control.UIAxes
        ThetaDistUIAxes            matlab.ui.control.UIAxes
        DeltaDistUIAxes            matlab.ui.control.UIAxes
        EMGThetaUIAxes             matlab.ui.control.UIAxes
        EMGDistUIAxes              matlab.ui.control.UIAxes
        stateUIAxes                matlab.ui.control.UIAxes
        RatioUIAxes                matlab.ui.control.UIAxes
        TriggerUIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        path = pwd;
        file = '100_CH9.continuous';
        EEG;
        EMG;
        maxt = 0;
        fs;
        ts;
        s;
        f = [1,30];
        t = [0,1];
        ps;
        ps_db;
        delta_power =[0,1];
        theta_power ;
        theta_delta_ratio =[0,1];
        s_emg;
        f_emg;
        t_emg;
        ps_emg;
        EMG_power =[0,1];
        mean_EMG_power;
        std_EMG_power;
        wake;
        nrem;
        rem;
        EMG_power_thre;
        EMG_power_thre_L;
        EMG_power_thre_H;
        delta_thre;
        theta_delta_ratio_thre;
        nrem_color=[1,0.5,0.5];
        rem_color=[0,0,0.8];
        wake_color=0.7*ones(1,3);
        delta_thre_tmp;
        theta_thre_tmp;
        EMG_thre_tmp;
        l_delta;
        l_delta_dist;
        l_theta;
        l_theta_dist;
        l_theta_2;
        l_EMG;
        l_EMG_dist;
        n_delta = 1;
        delta_x;
        n_theta = 1;
        theta_x;
        n_EMG = 1;
        EMG_x;
        state_code;
        state_x;
        EEG_sel;
        EMG_sel;
        ts_sel;
        n_delta_sel = 1;
        delta_x_sel;
        n_theta_sel = 1;
        theta_x_sel;
        n_EMG_sel = 1;
        EMG_x_sel;
        nrem_ratio = 0;
        rem_ratio = 0;
        wake_ratio = 0;
        nrem_ratio_seg =[];
        rem_ratio_seg = [];
        wake_ratio_seg = [];
        prepro_file;
        start_t;
        end_t;
        seg_len;
        delta_from;
        delta_to;
        theta_from;
        theta_to;
        EMG_from;
        EMG_to;
        t11;
        t12;
        t22;
        triggers;
        start_trg;
        end_trg;
        sel_range_on;
        sel_range_off;
        TTL;
    end
    methods (Access = private)
        function plot_index(app, ax,start_seg, end_seg,color,marker_alpha, marker_range)
            if nargin<5
                marker_alpha=0.3;
                marker_range=[-100,100];
            elseif  nargin<6
                marker_range=[-100,100];
            end
            overlap = app.OverlapsEditField.Value;
            
            for i=1:length(start_seg)
                patch(ax,[app.t(start_seg(i))-overlap/2,app.t(end_seg(i))+overlap/2,app.t(end_seg(i))+overlap/2,app.t(start_seg(i))-overlap/2],...
                    [marker_range(1),marker_range(1),marker_range(2),marker_range(2)],color,'facealpha',marker_alpha,'edgecolor','none');
            end
        end
        
        function delta_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.delta_thre_tmp = evt.CurrentPosition(1,1);
                    delete(app.l_delta_dist)
                    ax12 = app.DeltaDistUIAxes;
                    hold(ax12,"on")
                    app.l_delta_dist = drawline(ax12,'Position',[app.delta_thre_tmp, 0;app.delta_thre_tmp,max(app.n_delta)],'Color','r');
                    addlistener(app.l_delta_dist,'ROIMoved',@app.delta_dist_events);
                    delete(app.t12);
                    app.t12 = text(ax12,app.delta_thre_tmp*1.1,max(app.n_delta)/2,num2str(app.delta_thre_tmp),'Color','r'); 
            end
        end
        
        function delta_dist_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.delta_thre_tmp = evt.CurrentPosition(1,1);
                    delete(app.l_delta)
                    ax1 = app.DeltaThetaUIAxes;
                    hold(ax1,"on")
                    app.l_delta = drawline(ax1,'Position',[app.delta_thre_tmp, min(app.theta_delta_ratio);app.delta_thre_tmp,max(app.theta_delta_ratio)],'Color','r');
                    addlistener(app.l_delta,'ROIMoved',@app.delta_events);
                    delete(app.t12);
                    app.t12 = text(ax12,app.delta_thre_tmp*1.1,max(app.n_delta)/2,num2str(app.delta_thre_tmp),'Color','r'); 
            end
        end
        
        function theta_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.theta_thre_tmp = evt.CurrentPosition(1,2);
                    delete(app.l_theta_dist)
                    ax11 = app.ThetaDistUIAxes;
                    hold(ax11,"on")
                    app.l_theta_dist = drawline(ax11,'Position',[0 app.theta_thre_tmp;max(app.n_theta) app.theta_thre_tmp],'Color','r');
                    addlistener(app.l_theta_dist,'ROIMoved',@app.theta_dist_events);
                    delete(app.t11);
                    app.t11 = text(ax11,max(app.n_theta)/2,app.theta_thre_tmp*1.1,num2str(app.theta_thre_tmp),'Color','r'); 
                    
                    delete(app.l_theta_2)
                    ax2 = app.EMGThetaUIAxes;
                    hold(ax2,"on")
                    app.l_theta_2 = drawline(ax2,'Position',[min(app.EMG_power), app.theta_thre_tmp;max(app.EMG_power),app.theta_thre_tmp],'Color','r');
                    addlistener(app.l_theta_2,'ROIMoved',@app.theta_2_events);
            end
        end
        
        function theta_dist_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.theta_thre_tmp = evt.CurrentPosition(1,2);
                    delete(app.l_theta)
                    ax1 = app.DeltaThetaUIAxes;
                    hold(ax1,"on")
                    app.l_theta = drawline(ax1,'Position',[min(app.delta_power), app.theta_thre_tmp;max(app.delta_power),app.theta_thre_tmp],'Color','r');
                    addlistener(app.l_theta,'ROIMoved',@app.theta_events);
                    delete(app.t11);
                    app.t11 = text(ax11,max(app.n_theta)/2,app.theta_thre_tmp*1.1,num2str(app.theta_thre_tmp),'Color','r'); 
                    
                    delete(app.l_theta_2)
                    ax2 = app.EMGThetaUIAxes;
                    hold(ax2,"on")
                    app.l_theta_2 = drawline(ax2,'Position',[min(app.EMG_power), app.theta_thre_tmp;max(app.EMG_power),app.theta_thre_tmp],'Color','r');
                    addlistener(app.l_theta_2,'ROIMoved',@app.theta_2_events);
            end
        end
        
        function EMG_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.EMG_thre_tmp = evt.CurrentPosition(1,1);
                    delete(app.l_EMG_dist)
                    ax22 = app.EMGDistUIAxes;
                    hold(ax22,"on")
                    app.l_EMG_dist = drawline(ax22,'Position',[app.EMG_thre_tmp, 0;app.EMG_thre_tmp,max(app.n_EMG)],'Color','r');
                    addlistener(app.l_EMG_dist,'ROIMoved',@app.EMG_dist_events);
                    delete(app.t22);
                    app.t22 = text(ax22,app.EMG_thre_tmp*1.1,max(app.n_EMG)/2,num2str(app.EMG_thre_tmp),'Color','r'); 
            end
        end
        
        function EMG_dist_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.EMG_thre_tmp = evt.CurrentPosition(1,1);
                    delete(app.l_EMG)
                    ax2 = app.EMGThetaUIAxes;
                    hold(ax2,"on")
                    app.l_EMG = drawline(ax2,'Position',[app.EMG_thre_tmp, min(app.theta_delta_ratio);app.EMG_thre_tmp,max(app.theta_delta_ratio)],'Color','r');
                    addlistener(app.l_EMG,'ROIMoved',@app.EMG_events);
                    delete(app.t22);
                    app.t22 = text(ax22,app.EMG_thre_tmp*1.1,max(app.n_EMG)/2,num2str(app.EMG_thre_tmp),'Color','r'); 
            end
        end
        
        function theta_2_events(app,src,evt)
            evname = evt.EventName;
            switch(evname)
                case{'ROIMoved'}
                    app.theta_thre_tmp = evt.CurrentPosition(1,2);
                    
                    delete(app.l_theta)
                    ax1 = app.DeltaThetaUIAxes;
                    hold(ax1,"on")
                    app.l_theta = drawline(ax1,'Position',[min(app.delta_power), app.theta_thre_tmp;max(app.delta_power),app.theta_thre_tmp],'Color','r');
                    addlistener(app.l_theta,'ROIMoved',@app.theta_events);
                    
                    delete(app.l_theta_dist)
                    ax11 = app.ThetaDistUIAxes;
                    hold(ax11,"on")
                    app.l_theta_dist = drawline(ax11,'Position',[0 app.theta_thre_tmp;max(app.n_theta) app.theta_thre_tmp],'Color','r');
                    addlistener(app.l_theta_dist,'ROIMoved',@app.theta_dist_events);
                    delete(app.t11);
                    app.t11 = text(ax11,max(app.n_theta)/2,app.theta_thre_tmp*1.1,num2str(app.theta_thre_tmp),'Color','r'); 
            end
        end
        
        function score(app)
            app.start_t = app.StartsEditField.Value;
            app.end_t = app.EndsEditField.Value;
            sel_range = find(app.t >= app.start_t & app.t <= app.end_t);
            dt = app.t(2)-app.t(1);
            total_len = length(sel_range);
            
            plot(app.deltaUIAxes, app.t, app.delta_power,'k');
            plot(app.thetaratioUIAxes, app.t, app.theta_delta_ratio,'k');
            plot(app.EMGpowerUIAxes, app.t_emg, app.EMG_power,'k');
            hold(app.EMGpowerUIAxes,'on');
            hold(app.deltaUIAxes,'on');
            hold(app.thetaratioUIAxes,'on');
            
            %% Kphtoh
            %             app.wake = find(app.EMG_power > app.EMG_power_thre);
            %             app.nrem = setdiff(find(app.delta_power > app.delta_thre), app.wake);
            %             app.rem = setdiff(find(app.theta_delta_ratio> app.theta_delta_ratio_thre), [app.wake,app.nrem]);
            %             app.wake = setdiff(1:length(app.EMG_power),[app.nrem,app.rem]);
            
            %% Dan Yang
            app.nrem = find(app.delta_power(sel_range) > app.delta_thre & app.EMG_power(sel_range) < app.EMG_power_thre_L);
            app.rem = find(app.delta_power(sel_range) <= app.delta_thre & ...
                app.theta_delta_ratio(sel_range) > app.theta_delta_ratio_thre & ...
                app.EMG_power(sel_range) < app.EMG_power_thre_H);
            app.wake = setdiff(1:total_len,[app.nrem,app.rem]);
            
            [start_nrem,end_nrem,lens_nrem]=find_bound(app.nrem);
            start_nrem=app.nrem(start_nrem);
            end_nrem=app.nrem(end_nrem);
            [start_rem,end_rem,lens_rem]=find_bound(app.rem);
            start_rem=app.rem(start_rem);
            end_rem=app.rem(end_rem);
            [start_wake,end_wake,lens_wake]=find_bound(app.wake);
            start_wake=app.wake(start_wake);
            end_wake=app.wake(end_wake);
            
            marker_alpha=0.3;
            
            marker_range=[min(app.EMG_power(sel_range) ),max(app.EMG_power(sel_range) )];
            plot_index(app, app.EMGpowerUIAxes,start_nrem, end_nrem,app.nrem_color,marker_alpha, marker_range)
            plot_index(app, app.EMGpowerUIAxes,start_rem, end_rem,app.rem_color,marker_alpha, marker_range)
            plot_index(app, app.EMGpowerUIAxes,start_wake, end_wake,app.wake_color,marker_alpha, marker_range)
            
            marker_range=[min(app.delta_power(sel_range) ),max(app.delta_power(sel_range) )];
            plot_index(app, app.deltaUIAxes,start_nrem, end_nrem,app.nrem_color,marker_alpha, marker_range)
            plot_index(app, app.deltaUIAxes,start_rem, end_rem,app.rem_color,marker_alpha, marker_range)
            plot_index(app, app.deltaUIAxes,start_wake, end_wake,app.wake_color,marker_alpha, marker_range)
            
            marker_range=[min(app.theta_delta_ratio(sel_range) ),max(app.theta_delta_ratio(sel_range) )];
            plot_index(app, app.thetaratioUIAxes,start_nrem, end_nrem,app.nrem_color,marker_alpha, marker_range)
            plot_index(app, app.thetaratioUIAxes,start_rem, end_rem,app.rem_color,marker_alpha, marker_range)
            plot_index(app, app.thetaratioUIAxes,start_wake, end_wake,app.wake_color,marker_alpha, marker_range)
            
            EMG_above = find(app.EMG_power(sel_range) > app.EMG_power_thre);
            EMG_below = setdiff(1:total_len,EMG_above);
            plot(app.EMGpowerUIAxes, app.t_emg(sel_range(EMG_above)),app.EMG_power(sel_range(EMG_above)),'r.');
            plot(app.EMGpowerUIAxes, app.t_emg(sel_range(EMG_below)),app.EMG_power(sel_range(EMG_below)),'b.');
            delta_above = find(app.delta_power(sel_range) > app.delta_thre);
            delta_below = setdiff(1:total_len,delta_above);
            plot(app.deltaUIAxes, app.t(sel_range(delta_above)),app.delta_power(sel_range(delta_above)),'r.');
            plot(app.deltaUIAxes, app.t(sel_range(delta_below)),app.delta_power(sel_range(delta_below)),'b.');
            
            theta_ratio_above = find(app.theta_delta_ratio(sel_range) > app.theta_delta_ratio_thre);
            theta_ratio_below = setdiff(1:total_len,theta_ratio_above);
            plot(app.thetaratioUIAxes, app.t(sel_range(theta_ratio_above)),app.theta_delta_ratio(sel_range(theta_ratio_above)),'r.');
            plot(app.thetaratioUIAxes, app.t(sel_range(theta_ratio_below)),app.theta_delta_ratio(sel_range(theta_ratio_below)),'b.');
            
            hold(app.EMGpowerUIAxes,'off');
            hold(app.deltaUIAxes,'off');
            hold(app.thetaratioUIAxes,'off');
            
            app.nrem_ratio = length(app.nrem)/total_len;
            app.rem_ratio = length(app.rem)/total_len;
            app.wake_ratio = length(app.wake)/total_len;
            
            app.nremLabel.Visible ='on';
            app.nremLabel.Text=['NREM: ',num2str(length(app.nrem)*dt),' s (',num2str(app.nrem_ratio*100,2),' %)'];
            app.nremLabel.FontColor = app.nrem_color;
            
            app.remLabel.Visible ='on';
            app.remLabel.Text=['REM: ',num2str(length(app.rem)*dt),' s (',num2str(app.rem_ratio*100,2),' %)'];
            app.remLabel.FontColor = app.rem_color;
            
            app.wakeLabel.Visible ='on';
            app.wakeLabel.Text=['WAKE: ',num2str(length(app.wake)*dt),' s (',num2str(app.wake_ratio*100,2),' %)'];
            app.wakeLabel.FontColor = app.wake_color;
            
            ax1 = app.DeltaThetaUIAxes;
            cla(ax1);
            hold(ax1,"on")
            plot(ax1, app.delta_power(app.nrem), app.theta_delta_ratio(app.nrem),'.','Color',app.nrem_color);
            plot(ax1, app.delta_power(app.rem), app.theta_delta_ratio(app.rem),'.','Color',app.rem_color);
            plot(ax1, app.delta_power(app.wake), app.theta_delta_ratio(app.wake),'.','Color',app.wake_color);
            app.l_delta = drawline(ax1,'Position',[app.delta_thre, min(app.theta_delta_ratio);app.delta_thre,max(app.theta_delta_ratio)],'Color','r');
            app.l_theta = drawline(ax1,'Position',[min(app.delta_power), app.theta_delta_ratio_thre;max(app.delta_power),app.theta_delta_ratio_thre],'Color','r');
            addlistener(app.l_delta,'ROIMoved',@app.delta_events);
            addlistener(app.l_theta,'ROIMoved',@app.theta_events);
            
            ax12 = app.DeltaDistUIAxes;
            hold(ax12,"on")
            delete(app.l_delta_dist)
            app.l_delta_dist = drawline(ax12,'Position',[app.delta_thre_tmp, 0;app.delta_thre_tmp,max(app.n_delta)],'Color','r');
            addlistener(app.l_delta_dist,'ROIMoved',@app.delta_dist_events);
            
            ax11 = app.ThetaDistUIAxes;
            hold(ax11,"on")
            delete(app.l_theta_dist);
            app.l_theta_dist = drawline(ax11,'Position',[0 app.theta_thre_tmp;max(app.n_theta) app.theta_thre_tmp],'Color','r');
            addlistener(app.l_theta_dist,'ROIMoved',@app.theta_dist_events);
            
            ax2 = app.EMGThetaUIAxes;
            cla(ax2);
            hold(ax2,"on")
            plot(ax2, app.EMG_power(app.nrem), app.theta_delta_ratio(app.nrem),'.','Color',app.nrem_color);
            plot(ax2, app.EMG_power(app.rem), app.theta_delta_ratio(app.rem),'.','Color',app.rem_color);
            plot(ax2, app.EMG_power(app.wake), app.theta_delta_ratio(app.wake),'.','Color',app.wake_color);
            app.l_EMG = drawline(ax2,'Position',[app.EMG_thre_tmp, min(app.theta_delta_ratio);app.EMG_thre_tmp,max(app.theta_delta_ratio)],'Color','r');
            app.l_theta_2 = drawline(ax2,'Position',[min(app.EMG_power), app.theta_thre_tmp;max(app.EMG_power),app.theta_thre_tmp],'Color','r');
            addlistener(app.l_EMG,'ROIMoved',@app.EMG_events);
            addlistener(app.l_theta_2,'ROIMoved',@app.theta_2_events);
            
            ax22 = app.EMGDistUIAxes;
            hold(ax22,"on")
            delete(app.l_EMG_dist)
            app.l_EMG_dist = drawline(ax22,'Position',[app.EMG_thre_tmp, 0;app.EMG_thre_tmp,max(app.n_EMG)],'Color','r');
            addlistener(app.l_EMG_dist,'ROIMoved',@app.EMG_dist_events);
            
            overlap = app.OverlapsEditField.Value;
            [sorted_start,i_sorted_start]=sort([start_wake,start_nrem,start_rem]);
            app.state_x=[];
            app.state_code=[];
            for i=1:length(sorted_start)
                if i_sorted_start(i)<=length(start_wake)
                    end_seg=end_wake(start_wake==sorted_start(i));
                    code = 0;
                elseif i_sorted_start(i)<=length(start_wake)+length(start_nrem)
                    end_seg=end_nrem(start_nrem==sorted_start(i));
                    code = 1;
                else
                    end_seg=end_rem(start_rem==sorted_start(i));
                    code = 2;
                end
                app.state_x=[app.state_x,app.t(sorted_start(i))-overlap/2,app.t(end_seg)+overlap/2];
                app.state_code=[app.state_code,code*ones(1,2)];
            end
            cla(app.stateUIAxes);
            plot(app.stateUIAxes,app.state_x, app.state_code,'k');
            app.stateUIAxes.YTick=0:2;
            app.stateUIAxes.YTickLabel={"W",'N','R'};
            xlim(app.stateUIAxes,[app.start_t,app.end_t]);
            ylim(app.stateUIAxes, [-1,3]);
            
            app.seg_len = app.SegmentlensEditField.Value;
            segs = app.start_t:app.seg_len:app.end_t;
            app.nrem_ratio_seg = [];
            app.rem_ratio_seg = [];
            app.wake_ratio_seg = [];
            start_idx = find(app.t >= app.start_t,1);
            for i = 1:length(segs)-1
                start_seg = find(app.t >= segs(i),1);
                end_seg = min([find(app.t >= segs(i+1),1)-1, length(app.t)]);
                app.nrem_ratio_seg(i) = length(find(app.nrem >= start_seg-start_idx+1 & app.nrem < end_seg-start_idx+1))/(end_seg-start_seg);
                app.rem_ratio_seg(i) = length(find(app.rem >= start_seg-start_idx+1 & app.rem < end_seg-start_idx+1))/(end_seg-start_seg);
                app.wake_ratio_seg(i) = length(find(app.wake >= start_seg-start_idx+1 & app.wake < end_seg-start_idx+1))/(end_seg-start_seg);
            end
            ax = app.RatioUIAxes;
            cla(ax);
            hold(ax,"on")
            plot(ax,1:length(segs)-1,app.nrem_ratio_seg,"Color",app.nrem_color,'LineWidth',2);
            plot(ax,1:length(segs)-1,app.rem_ratio_seg,"Color",app.rem_color,'LineWidth',2);
            plot(ax,1:length(segs)-1,app.wake_ratio_seg,"Color",app.wake_color,'LineWidth',2);
            ylim(ax,[0,1]);
            if length(segs)>1
            xlim(ax,[1,length(segs)-1]);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadEEGButton
        function LoadEEGButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.continuous','Select EEG data file',app.path);
            if ~isequal(app.file,0)
                [app.EEG, ~, ~] = load_open_ephys_data(fullfile(app.path,app.file));
                app.fs=app.fsHzEditField.Value;
                app.maxt=length(app.EEG)/app.fs;
                app.ts=1/app.fs:1/app.fs:app.maxt;
                plot(app.EEGUIAxes, app.ts, app.EEG,'k');
                app.start_view_EditField.Value = 0;
                app.end_view_EditField.Value = app.maxt;
                xlim(app.EEGUIAxes,[0,app.maxt]);
                app.StartsEditField.Value = 0;
                app.EndsEditField.Value=app.maxt;
            end
        end

        % Callback function
        function StarttSliderValueChanged(app, event)
            
        end

        % Value changed function: start_view_EditField
        function start_view_EditFieldValueChanged(app, event)
            start_view = app.start_view_EditField.Value;
            end_view = app.end_view_EditField.Value;
            xlim(app.EEGUIAxes,[start_view,end_view]);
            xlim(app.EMGUIAxes,[start_view,end_view]);
            xlim(app.spectrogramUIAxes,[start_view,end_view]);
            xlim(app.deltaUIAxes,[start_view,end_view]);
            xlim(app.thetaratioUIAxes,[start_view,end_view]);
            xlim(app.EMGpowerUIAxes,[start_view,end_view]);
            xlim(app.stateUIAxes,[start_view,end_view]);
        end

        % Button pushed function: LoadEMGButton
        function LoadEMGButtonPushed(app, event)
            [file,app.path] = uigetfile('*.continuous','Select EMG data file',app.path);
            if ~isequal(file,0)
                [app.EMG, ~, ~] = load_open_ephys_data(fullfile(app.path,file));
                app.fs=app.fsHzEditField.Value;
                app.maxt=length(app.EMG)/app.fs;
                app.ts=1/app.fs:1/app.fs:app.maxt;
                plot(app.EMGUIAxes, app.ts, app.EMG,'k');
                app.start_view_EditField.Value = 0;
                app.end_view_EditField.Value = app.maxt;
                xlim(app.EMGUIAxes,[0,app.maxt]);
                app.StartsEditField.Value = 0;
                app.EndsEditField.Value=app.maxt;
            end
        end

        % Button pushed function: FullViewButton
        function FullViewButtonPushed(app, event)
            %             app.start_t = app.StartsEditField.Value;
            %             app.end_t = app.EndsEditField.Value;
            app.start_t =0;
            app.end_t = app.maxt;
            xlim(app.EEGUIAxes,[app.start_t,app.end_t]);
            xlim(app.EMGUIAxes,[app.start_t,app.end_t]);
            xlim(app.spectrogramUIAxes,[app.start_t,app.end_t]);
            xlim(app.deltaUIAxes,[app.start_t,app.end_t]);
            xlim(app.thetaratioUIAxes,[app.start_t,app.end_t]);
            xlim(app.EMGpowerUIAxes,[app.start_t,app.end_t]);
            xlim(app.stateUIAxes,[app.start_t,app.end_t]);
            app.start_view_EditField.Value = app.start_t;
            app.end_view_EditField.Value = app.end_t;
            app.StartsEditField.Value = app.start_t;
            app.EndsEditField.Value = app.end_t;
        end

        % Button pushed function: EEGSpectrogramButton
        function EEGSpectrogramButtonPushed(app, event)
            %app.fs=app.fsHzEditField.Value;
            app.start_t = app.StartsEditField.Value;
            app.end_t = app.EndsEditField.Value;
            app.start_view_EditField.Value = app.start_t;
            app.end_view_EditField.Value = app.end_t;
            sel_range = find(app.ts>=app.start_t  & app.ts<=app.end_t);
            app.EEG_sel = app.EEG(sel_range);
            app.ts_sel = app.ts(sel_range);
            win_len = app.WinLensEditField.Value;
            overlap = app.OverlapsEditField.Value;
            [app.s,app.f,app.t,app.ps]=spectrogram(app.EEG_sel,win_len*app.fs,overlap*app.fs,0.5:0.1:20,app.fs);
            app.s=abs(app.s);
            app.ps_db=10*log10(app.ps);
            app.t = app.t + app.start_t;
            imagesc(app.spectrogramUIAxes, app.t,app.f,app.ps_db);
            caxis(app.spectrogramUIAxes,[-2,30]);
            axis(app.spectrogramUIAxes,"xy");
            colormap(app.spectrogramUIAxes, jet);
            xlim(app.spectrogramUIAxes,[app.start_t,app.end_t]);
            
            delta_range=find(app.f>=0.65 & app.f<=4);
            theta_range=find(app.f>=6 & app.f<=12);
            app.delta_power=sum(app.ps(delta_range,:));
            app.theta_power=sum(app.ps(theta_range,:));
            app.theta_delta_ratio=app.theta_power./app.delta_power;
            plot(app.deltaUIAxes, app.t, app.delta_power,'k');
            xlim(app.deltaUIAxes,[app.start_t,app.end_t]);
            plot(app.thetaratioUIAxes, app.t, app.theta_delta_ratio,'k');
            xlim(app.thetaratioUIAxes,[app.start_t,app.end_t]);
            
            app.EMG_sel = app.EMG(sel_range);
            [app.s_emg,app.f_emg,app.t_emg,app.ps_emg]=spectrogram(app.EMG_sel,win_len*app.fs,overlap*app.fs,20:300,app.fs,'yaxis');
            app.t_emg=app.t_emg + app.start_t;
            app.EMG_power=mean(app.ps_emg,1);
            app.mean_EMG_power=mean(app.EMG_power);
            app.std_EMG_power = std(app.EMG_power(app.EMG_power <= app.mean_EMG_power));
            plot(app.EMGpowerUIAxes, app.t_emg, app.EMG_power,'k');
            xlim(app.EMGpowerUIAxes,[app.start_t,app.end_t]);
            
            if isempty(app.delta_thre_tmp)
                app.EMG_thre_tmp = app.mean_EMG_power;
                app.delta_thre_tmp = mean(app.delta_power);
                app.theta_thre_tmp = mean(app.theta_delta_ratio)+std(app.theta_delta_ratio);
            end
            
            ax1 = app.DeltaThetaUIAxes;
            cla(ax1);
            hold(ax1,"on")
            plot(ax1, app.delta_power,app.theta_delta_ratio,'k.');
            app.l_delta = drawline(ax1,'Position',[app.delta_thre_tmp, min(app.theta_delta_ratio);app.delta_thre_tmp,max(app.theta_delta_ratio)],'Color','r');
            app.l_theta = drawline(ax1,'Position',[min(app.delta_power), app.theta_thre_tmp;max(app.delta_power),app.theta_thre_tmp],'Color','r');
            addlistener(app.l_delta,'ROIMoved',@app.delta_events);
            addlistener(app.l_theta,'ROIMoved',@app.theta_events);
            axis(ax1,'tight')
            
            [app.n_delta,edge_delta]=histcounts(app.delta_power,app.bins_delta_EditField.Value);
            app.delta_x = edge_delta(1:end-1)+(edge_delta(2)-edge_delta(1))/2;
            
            ax12 = app.DeltaDistUIAxes;
            cla(ax12);
            hold(ax12,"on")
            bar(ax12,app.delta_x,app.n_delta,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_delta_dist = drawline(ax12,'Position',[app.delta_thre_tmp, 0;app.delta_thre_tmp,max(app.n_delta)],'Color','r');     
            addlistener(app.l_delta_dist,'ROIMoved',@app.delta_dist_events);
            app.t12 = text(ax12,app.delta_thre_tmp*1.1,max(app.n_delta)/2,num2str(app.delta_thre_tmp),'Color','r');     
            axis(ax12,'tight');
            app.delta_from_EditField.Value = min(app.delta_x);
            app.delta_to_EditField.Value = max(app.delta_x);
            
            [app.n_theta,edge_theta]=histcounts(app.theta_delta_ratio,app.bins_theta_EditField.Value);
            app.theta_x = edge_theta(1:end-1)+ (edge_theta(2)-edge_theta(1))/2;
            ax11 = app.ThetaDistUIAxes;
            cla(ax11);
            hold(ax11,"on")
            barh(ax11,app.theta_x,app.n_theta,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_theta_dist = drawline(ax11,'Position',[0 app.theta_thre_tmp;max(app.n_theta) app.theta_thre_tmp],'Color','r');
            addlistener(app.l_theta_dist,'ROIMoved',@app.theta_dist_events);
            app.t11 = text(ax11,max(app.n_theta)/2,app.theta_thre_tmp*1.1,num2str(app.theta_thre_tmp),'Color','r');
            axis(ax11,'tight')
            app.theta_from_EditField.Value = min(app.theta_x);
            app.theta_to_EditField.Value = max(app.theta_x);
            
            ax2 = app.EMGThetaUIAxes;
            cla(ax2);
            hold(ax2,"on")
            plot(ax2, app.EMG_power, app.theta_delta_ratio,'k.')
            app.l_EMG = drawline(ax2,'Position',[app.EMG_thre_tmp, min(app.theta_delta_ratio);app.EMG_thre_tmp,max(app.theta_delta_ratio)],'Color','r');
            app.l_theta_2 = drawline(ax2,'Position',[min(app.EMG_power), app.theta_thre_tmp;max(app.EMG_power),app.theta_thre_tmp],'Color','r');
            addlistener(app.l_EMG,'ROIMoved',@app.EMG_events);
            addlistener(app.l_theta_2,'ROIMoved',@app.theta_2_events);
            axis(ax2,'tight')
            
            [app.n_EMG,edge_EMG]=histcounts(app.EMG_power,app.bins_EMG_EditField.Value);
            app.EMG_x = edge_EMG(1:end-1)+(edge_EMG(2)-edge_EMG(1))/2;
            ax22 = app.EMGDistUIAxes;
            cla(ax22);
            hold(ax22,"on")
            bar(ax22,app.EMG_x,app.n_EMG,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_EMG_dist = drawline(ax22,'Position',[app.EMG_thre_tmp, 0;app.EMG_thre_tmp,max(app.n_EMG)],'Color','r');
            addlistener(app.l_EMG_dist,'ROIMoved',@app.EMG_dist_events);
            app.t22 = text(ax22,app.EMG_thre_tmp*1.1,max(app.n_EMG)/2,num2str(app.EMG_thre_tmp),'Color','r');    
            axis(ax22,'tight')
            app.EMG_from_EditField.Value = min(app.EMG_x);
            app.EMG_to_EditField.Value = max(app.EMG_x);
            
            xlim(app.stateUIAxes,[app.start_t,app.end_t]);
        end

        % Value changed function: end_view_EditField
        function end_view_EditFieldValueChanged(app, event)
            end_view = app.end_view_EditField.Value;
            start_view = app.start_view_EditField.Value;
            xlim(app.EEGUIAxes,[start_view,end_view]);
            xlim(app.EMGUIAxes,[start_view,end_view]);
            xlim(app.spectrogramUIAxes,[start_view,end_view]);
            xlim(app.deltaUIAxes,[start_view,end_view]);
            xlim(app.thetaratioUIAxes,[start_view,end_view]);
            xlim(app.EMGpowerUIAxes,[start_view,end_view]);
            xlim(app.stateUIAxes,[start_view,end_view]);
        end

        % Value changed function: fsHzEditField
        function fsHzEditFieldValueChanged(app, event)
            app.fs = app.fsHzEditField.Value;
        end

        % Button down function: deltaUIAxes
        function deltaUIAxesButtonDown(app, event)
            if app.SelROICheckBox.Value == 1
                p = app.deltaUIAxes.CurrentPoint;
                startx = floor(p(1,1)/2.5)*2.5;
                w = app.EpochEditField.Value;
                h = app.deltaUIAxes.YLim(2)-app.deltaUIAxes.YLim(1);
                rect = [startx, 0, app.EpochEditField.Value, app.deltaUIAxes.YLim(2)-app.deltaUIAxes.YLim(1)];
                h2 = images.roi.Rectangle(app.deltaUIAxes,'Position',rect,'Color','r');
                % patch(app.deltaUIAxes,[startx,startx+w,startx+w,startx],[0,0,h,h],'r','alpha',0.1);
            end
        end

        % Button pushed function: AutoScoreButton
        function AutoScoreButtonPushed(app, event)
            app.EMG_power_thre = app.mean_EMG_power;
            app.EMG_power_thre_L = app.mean_EMG_power-app.std_EMG_power;
            app.EMG_power_thre_H = app.mean_EMG_power+app.std_EMG_power;
            app.delta_thre = mean(app.delta_power);
            app.theta_delta_ratio_thre = mean(app.theta_delta_ratio)+std(app.theta_delta_ratio);
            app.EMG_thre_tmp = app.mean_EMG_power;
            app.delta_thre_tmp = app.delta_thre;
            app.theta_thre_tmp = app.theta_delta_ratio_thre;
            score(app);
        end

        % Button pushed function: ManualThresholdButton
        function ManualThresholdButtonPushed(app, event)
            app.EMG_power_thre = app.EMG_thre_tmp;
            app.EMG_power_thre_L = app.EMG_thre_tmp;
            app.EMG_power_thre_H = app.EMG_thre_tmp;
            app.delta_thre = app.delta_thre_tmp;
            app.theta_delta_ratio_thre = app.theta_thre_tmp;
            score(app);
        end

        % Value changed function: bins_theta_EditField
        function bins_theta_EditFieldValueChanged(app, event)
            [app.n_theta,edge_theta]=histcounts(app.theta_delta_ratio,app.bins_theta_EditField.Value);
            app.theta_x = edge_theta(1:end-1)+ (edge_theta(2)-edge_theta(1))/2;
            ax11 = app.ThetaDistUIAxes;
            cla(ax11);
            hold(ax11,"on")
            barh(ax11,app.theta_x,app.n_theta,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_theta_dist = drawline(ax11,'Position',[0 app.theta_thre_tmp;max(app.n_theta) app.theta_thre_tmp],'Color','r');
            addlistener(app.l_theta_dist,'ROIMoved',@app.theta_dist_events);
            app.t11 = text(ax11,max(app.n_theta)/2,app.theta_thre_tmp*1.1,num2str(app.theta_thre_tmp),'Color','r');     
            axis(ax11,'tight');
        end

        % Value changed function: bins_delta_EditField
        function bins_delta_EditFieldValueChanged(app, event)
            [app.n_delta,edge_delta]=histcounts(app.delta_power,app.bins_delta_EditField.Value);
            app.delta_x = edge_delta(1:end-1)+(edge_delta(2)-edge_delta(1))/2;
            
            ax12 = app.DeltaDistUIAxes;
            cla(ax12);
            hold(ax12,"on")
            bar(ax12,app.delta_x,app.n_delta,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_delta_dist = drawline(ax12,'Position',[app.delta_thre_tmp, 0;app.delta_thre_tmp,max(app.n_delta)],'Color','r');
            addlistener(app.l_delta_dist,'ROIMoved',@app.delta_dist_events);
            app.t12 = text(ax12,app.delta_thre_tmp*1.1,max(app.n_delta)/2,num2str(app.delta_thre_tmp),'Color','r');   
            axis(ax12,'tight')
        end

        % Value changed function: bins_EMG_EditField
        function bins_EMG_EditFieldValueChanged(app, event)
            [app.n_EMG,edge_EMG]=histcounts(app.EMG_power,app.bins_EMG_EditField.Value);
            app.EMG_x = edge_EMG(1:end-1)+(edge_EMG(2)-edge_EMG(1))/2;
            
            ax22 = app.EMGDistUIAxes;
            cla(ax22);
            hold(ax22,"on")
            bar(ax22,app.EMG_x,app.n_EMG,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_EMG_dist = drawline(ax22,'Position',[app.EMG_thre_tmp, 0;app.EMG_thre_tmp,max(app.n_EMG)],'Color','r');
            addlistener(app.l_EMG_dist,'ROIMoved',@app.EMG_dist_events);
            app.t22 = text(ax22,app.EMG_thre_tmp*1.1,max(app.n_EMG)/2,num2str(app.EMG_thre_tmp),'Color','r');   
            axis(ax22,'tight')
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
          
            props = properties(app);
            %             save_command = "save(fullfile(app.path,strcat(app.file,'_processed.mat'))";
            for iprop = 1:length(props)
                thisprop = props{iprop};
                thisprop_value = app.(thisprop);
                assignin('base',thisprop,thisprop_value);
                %                 save_command = strcat(save_command,",'",thisprop,"'");
            end
            %             save_command =  strcat(save_command,");");
            if app.start_t>0 || app.end_t<app.maxt
                postfix = strcat('_processed_',num2str(round(app.start_t)),'_',num2str(round(app.end_t)));
            else
                postfix = '_processed';
            end
            save_command =  "save(fullfile(path,strcat(file,'" + postfix +".mat')),'EEG'," + ...
                "'EMG','maxt','fs','ts','s','f','t','ps','ps_db','delta_power'," + ...
                "'delta_power','theta_power','theta_delta_ratio','s_emg','f_emg'," + ...
                "'t_emg','ps_emg','EMG_power','mean_EMG_power','std_EMG_power'," + ...
                "'wake','nrem','rem','EMG_power_thre','EMG_power_thre_L'," + ...
                "'EMG_power_thre_H','delta_thre','theta_delta_ratio_thre'," + ...
                "'nrem_color','rem_color','wake_color','delta_thre_tmp',"+ ...
                "'theta_thre_tmp','EMG_thre_tmp','l_delta','l_delta_dist',"+ ...
                "'l_theta','l_theta_dist','l_theta_2','l_EMG','l_EMG_dist'," + ...
                "'n_delta','delta_x','n_theta','theta_x','n_EMG','EMG_x'," +...
                "'state_code','state_x','EEG_sel','EMG_sel','ts_sel',"+...
                "'n_delta_sel','delta_x_sel','n_theta_sel','theta_x_sel',"+...
                "'n_EMG_sel','EMG_x_sel','nrem_ratio','rem_ratio','wake_ratio',"+...
                "'nrem_ratio_seg','rem_ratio_seg','wake_ratio_seg',"+...
                "'EndsEditField','StartsEditField','WinLensEditField',"+...
                "'OverlapsEditField','start_t','end_t','seg_len','file',"+...
                "'delta_from','delta_to','theta_from','theta_to','EMG_from','EMG_to');";
            disp("excute the following command to save variables:");
            disp(save_command);
            disp('clear;');
            
            sel_range = find(app.t >= app.start_t & app.t <= app.end_t);
            
            fig = figure("Position",[100,10,1249,998]);
            fig.Visible = 'off';
            start_x = 48;
            wid1 = 100;
            start_x2 = 720;
            start_y2 = 750;
            wid2 = 190;
            
            EEGAxes = axes(fig,"Position",[start_x/1249,850/998,641/1249,wid1/998]);
            allChildren = app.EEGUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, EEGAxes);
            xlim(EEGAxes,[app.start_t,app.end_t]);
            title("EEG");
            ylabel("uV");
            
            EMGAxes = axes('Position',[start_x/1249,700/998,641/1249,wid1/998]);
            allChildren = app.EMGUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, EMGAxes);
            xlim(EMGAxes,[app.start_t,app.end_t]);
            title("EMG");
            ylabel("uV")
            
            EEG_SpecAxes = axes('Position',[start_x/1249,550/998,641/1249,wid1/998]);
            allChildren = app.spectrogramUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, EEG_SpecAxes);
            xlim(EEG_SpecAxes,[app.start_t,app.end_t]);
            ylim([app.f(1),app.f(end)]);
            caxis(EEG_SpecAxes,[-2,30]);
            axis(EEG_SpecAxes,"xy");
            colormap(EEG_SpecAxes, jet);
            title("EEG spectrogram");
            ylabel("dB")
            
            deltaAxes = axes('Position',[start_x/1249,400/998,641/1249,wid1/998]);
            allChildren = app.deltaUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, deltaAxes);
            xlim(deltaAxes,[app.start_t,app.end_t]);
            ylim(deltaAxes,[min(app.delta_power(sel_range)),max(app.delta_power(sel_range))]);
            title("\delta Power");
            ylabel("uV^2");
            
            thetaAxes = axes('Position',[start_x/1249,270/998,641/1249,wid1/998]);
            allChildren = app.thetaratioUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, thetaAxes);
            xlim(thetaAxes,[app.start_t,app.end_t]);
            ylim(thetaAxes,[min(app.theta_delta_ratio(sel_range)),max(app.theta_delta_ratio(sel_range))]);
            title("\theta / \delta");
            ylabel("ratio");
            
            EMGAxes = axes('Position',[start_x/1249,140/998,641/1249,wid1/998]);
            allChildren = app.EMGpowerUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, EMGAxes);
            xlim(EMGAxes,[app.start_t,app.end_t]);
            ylim(EMGAxes,[min(app.EMG_power(sel_range)),max(app.EMG_power(sel_range))]);
            title("EMG Power");
            ylabel("uV^2");
            xlabel("Time(s)");
            
            app.delta_from = app.delta_from_EditField.Value;
            app.delta_to = app.delta_to_EditField.Value;
            app.theta_from = app.theta_from_EditField.Value;
            app.theta_to = app.theta_to_EditField.Value;
            app.EMG_from = app.EMG_from_EditField.Value;
            app.EMG_to = app.EMG_to_EditField.Value;
            
            delta_theta_Axes = axes('Position',[(start_x2+100)/1249,start_y2/998,wid2/1249,wid2/998]);
            allChildren = app.DeltaThetaUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, delta_theta_Axes);
            ylim(delta_theta_Axes,[app.theta_from, app.theta_to]);
            xlim(delta_theta_Axes,[app.delta_from, app.delta_to]);
            
            theta_dist_Axes = axes('Position',[start_x2/1249,start_y2/998,80/1249,wid2/998]);
            allChildren = app.ThetaDistUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, theta_dist_Axes);
            ylim(theta_dist_Axes,[app.theta_from, app.theta_to]);
            ylabel("\theta / \delta");
            
            delta_dist_Axes = axes('Position',[(start_x2+100)/1249,(start_y2-113)/998,wid2/1249,80/998]);
            allChildren = app.DeltaDistUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, delta_dist_Axes);
            xlim(delta_dist_Axes,[app.delta_from, app.delta_to]);
            xlabel("\delta Power");
            
            EMG_theta_Axes = axes('Position',[(start_x2+320)/1249,start_y2/998,wid2/1249,wid2/998]);
            allChildren = app.EMGThetaUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, EMG_theta_Axes);
            ylim(EMG_theta_Axes,[app.theta_from, app.theta_to]);
            xlim(EMG_theta_Axes,[app.EMG_from, app.EMG_to]);
            
            EMG_dist_Axes = axes('Position',[(start_x2+320)/1249,(start_y2-113)/998,wid2/1249,80/998]);
            allChildren = app.EMGDistUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, EMG_dist_Axes);
            xlim(EMG_dist_Axes,[app.EMG_from, app.EMG_to]);
            xlabel("EMG Power");
            
            stateAxes = axes('Position',[start_x/1249,15/998,641/1249,90/998]);
            allChildren = app.stateUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, stateAxes);
            stateAxes.YTick=0:2;
            stateAxes.YTickLabel={"W",'N','R'};
            xlim(stateAxes,[app.start_t,app.end_t]);
            ylim(stateAxes, [-1,3]);
            
            segAxes = axes('Position',[start_x2/1249,400/998,(wid2*3)/1249,100/998]);
            allChildren = app.RatioUIAxes.XAxis.Parent.Children;
            copyobj(allChildren, segAxes);
            xlabel("Segment #");
            ylabel("Ratio");
            %             legend("NREM","REM","WAKE","Location","northoutside");
            
            dt = app.t(2)-app.t(1);
            str = ['NREM: ',num2str(length(app.nrem)*dt),' s (',num2str(app.nrem_ratio*100,2),' %)'];
            dim = [start_x2/1249,520/998,wid2/1249,80/998];
            annotation('textbox',dim,'String',str,'FitBoxToText','on','Color',app.nrem_color,'EdgeColor','none');
            str = ['REM: ',num2str(length(app.rem)*dt),' s (',num2str(app.rem_ratio*100,2),' %)'];
            dim = [start_x2/1249,500/998,wid2/1249,80/998];
            annotation('textbox',dim,'String',str,'FitBoxToText','on','Color',app.rem_color,'EdgeColor','none');
            str = ['WAKE: ',num2str(length(app.wake)*dt),' s (',num2str(app.wake_ratio*100,2),' %)'];
            dim = [start_x2/1249,480/998,wid2/1249,80/998];
            annotation('textbox',dim,'String',str,'FitBoxToText','on','Color',app.wake_color,'EdgeColor','none');
            saveas(fig, fullfile(app.path,strcat(app.file,postfix,".png")), 'png');
            savefig(fig, fullfile(app.path,strcat(app.file,postfix,".fig")));
            delete(fig);
        end

        % Value changed function: delta_from_EditField
        function delta_from_EditFieldValueChanged(app, event)
            app.delta_from = app.delta_from_EditField.Value;
            app.delta_to = app.delta_to_EditField.Value;
            xlim(app.DeltaThetaUIAxes,[app.delta_from, app.delta_to]);
            xlim(app.DeltaDistUIAxes,[app.delta_from, app.delta_to]);
        end

        % Value changed function: delta_to_EditField
        function delta_to_EditFieldValueChanged(app, event)
            app.delta_from = app.delta_from_EditField.Value;
            app.delta_to = app.delta_to_EditField.Value;
            xlim(app.DeltaThetaUIAxes,[app.delta_from, app.delta_to]);
            xlim(app.DeltaDistUIAxes,[app.delta_from, app.delta_to]);
        end

        % Value changed function: EMG_from_EditField
        function EMG_from_EditFieldValueChanged(app, event)
            app.EMG_from = app.EMG_from_EditField.Value;
            app.EMG_to = app.EMG_to_EditField.Value;
            xlim(app.EMGThetaUIAxes,[app.EMG_from, app.EMG_to]);
            xlim(app.EMGDistUIAxes,[app.EMG_from, app.EMG_to]);
        end

        % Value changed function: EMG_to_EditField
        function EMG_to_EditFieldValueChanged(app, event)
            app.EMG_from = app.EMG_from_EditField.Value;
            app.EMG_to = app.EMG_to_EditField.Value;
            xlim(app.EMGThetaUIAxes,[app.EMG_from, app.EMG_to]);
            xlim(app.EMGDistUIAxes,[app.EMG_from, app.EMG_to]);
        end

        % Value changed function: theta_from_EditField
        function theta_from_EditFieldValueChanged(app, event)
            app.theta_from = app.theta_from_EditField.Value;
            app.theta_to = app.theta_to_EditField.Value;
            ylim(app.DeltaThetaUIAxes,[app.theta_from, app.theta_to]);
            ylim(app.EMGThetaUIAxes,[app.theta_from, app.theta_to]);
            ylim(app.ThetaDistUIAxes,[app.theta_from, app.theta_to]);
        end

        % Value changed function: theta_to_EditField
        function theta_to_EditFieldValueChanged(app, event)
            app.theta_from = app.theta_from_EditField.Value;
            app.theta_to = app.theta_to_EditField.Value;
            ylim(app.DeltaThetaUIAxes,[app.theta_from, app.theta_to]);
            ylim(app.EMGThetaUIAxes,[app.theta_from, app.theta_to]);
            ylim(app.ThetaDistUIAxes,[app.theta_from, app.theta_to]);
        end

        % Button pushed function: NarrowDownButton
        function NarrowDownButtonPushed(app, event)
            app.start_t = app.StartsEditField.Value;
            app.end_t = app.EndsEditField.Value;
            app.start_view_EditField.Value = app.start_t;
            app.end_view_EditField.Value = app.end_t;
            xlim(app.spectrogramUIAxes,[app.start_t,app.end_t]);
            xlim(app.deltaUIAxes,[app.start_t,app.end_t]);
            xlim(app.thetaratioUIAxes,[app.start_t,app.end_t]);
            xlim(app.EMGpowerUIAxes,[app.start_t,app.end_t]);
            xlim(app.stateUIAxes,[app.start_t,app.end_t]);
            
            sel_range = find(app.t>=app.start_t  & app.t<=app.end_t);
            
            ax1 = app.DeltaThetaUIAxes;
            cla(ax1);
            hold(ax1,"on")
            plot(ax1, app.delta_power(sel_range),app.theta_delta_ratio(sel_range),'k.');
            app.l_delta = drawline(ax1,'Position',[app.delta_thre_tmp, min(app.theta_delta_ratio(sel_range));app.delta_thre_tmp,max(app.theta_delta_ratio(sel_range))],'Color','r');
            app.l_theta = drawline(ax1,'Position',[min(app.delta_power(sel_range)), app.theta_thre_tmp;max(app.delta_power(sel_range)),app.theta_thre_tmp],'Color','r');
            addlistener(app.l_delta,'ROIMoved',@app.delta_events);
            addlistener(app.l_theta,'ROIMoved',@app.theta_events); 
            axis(ax1,'tight')
            
            [app.n_delta_sel,edge_delta]=histcounts(app.delta_power(sel_range),app.bins_delta_EditField.Value);
            app.delta_x_sel = edge_delta(1:end-1)+(edge_delta(2)-edge_delta(1))/2;
            
            ax12 = app.DeltaDistUIAxes;
            cla(ax12);
            hold(ax12,"on")
            bar(ax12,app.delta_x_sel,app.n_delta_sel,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_delta_dist = drawline(ax12,'Position',[app.delta_thre_tmp, 0;app.delta_thre_tmp,max(app.n_delta_sel)],'Color','r');
            addlistener(app.l_delta_dist,'ROIMoved',@app.delta_dist_events);
            app.t12 = text(ax12,app.delta_thre_tmp*1.1,max(app.n_delta_sel)/2,num2str(app.delta_thre_tmp),'Color','r');  
            axis(ax12,'tight')
            app.delta_from_EditField.Value = min(app.delta_x_sel);
            app.delta_to_EditField.Value = max(app.delta_x_sel);
            
            [app.n_theta_sel,edge_theta]=histcounts(app.theta_delta_ratio(sel_range),app.bins_theta_EditField.Value);
            app.theta_x_sel = edge_theta(1:end-1)+ (edge_theta(2)-edge_theta(1))/2;
            ax11 = app.ThetaDistUIAxes;
            cla(ax11);
            hold(ax11,"on")
            barh(ax11,app.theta_x_sel,app.n_theta_sel,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_theta_dist = drawline(ax11,'Position',[0 app.theta_thre_tmp;max(app.n_theta_sel) app.theta_thre_tmp],'Color','r');
            addlistener(app.l_theta_dist,'ROIMoved',@app.theta_dist_events);
            app.t11 = text(ax11,max(app.n_theta_sel)/2,app.theta_thre_tmp*1.1,num2str(app.theta_thre_tmp),'Color','r');  
            axis(ax11,'tight')
            app.theta_from_EditField.Value = min(app.theta_x_sel);
            app.theta_to_EditField.Value = max(app.theta_x_sel);
            
            ax2 = app.EMGThetaUIAxes;
            cla(ax2);
            hold(ax2,"on")
            plot(ax2, app.EMG_power(sel_range), app.theta_delta_ratio(sel_range),'k.')
            app.l_EMG = drawline(ax2,'Position',[app.EMG_thre_tmp, min(app.theta_delta_ratio(sel_range));app.EMG_thre_tmp,max(app.theta_delta_ratio(sel_range))],'Color','r');
            app.l_theta_2 = drawline(ax2,'Position',[min(app.EMG_power(sel_range)), app.theta_thre_tmp;max(app.EMG_power(sel_range)),app.theta_thre_tmp],'Color','r');
            addlistener(app.l_EMG,'ROIMoved',@app.EMG_events);
            addlistener(app.l_theta_2,'ROIMoved',@app.theta_2_events);
            axis(ax2,'tight')
            
            [app.n_EMG_sel,edge_EMG]=histcounts(app.EMG_power(sel_range),app.bins_EMG_EditField.Value);
            app.EMG_x_sel = edge_EMG(1:end-1)+(edge_EMG(2)-edge_EMG(1))/2;
            ax22 = app.EMGDistUIAxes;
            cla(ax22);
            hold(ax22,"on")
            bar(ax22,app.EMG_x_sel,app.n_EMG_sel,'FaceColor',0.3*ones(1,3),'EdgeColor','none');
            app.l_EMG_dist = drawline(ax22,'Position',[app.EMG_thre_tmp, 0;app.EMG_thre_tmp,max(app.n_EMG_sel)],'Color','r');
            addlistener(app.l_EMG_dist,'ROIMoved',@app.EMG_dist_events);
            app.t22 = text(ax22,app.EMG_thre_tmp*1.1,max(app.n_EMG_sel)/2,num2str(app.EMG_thre_tmp),'Color','r');  
            axis(ax22,'tight');
            app.EMG_from_EditField.Value = min(app.EMG_x_sel);
            app.EMG_to_EditField.Value = max(app.EMG_x_sel);
        end

        % Button pushed function: RatioChangeButton
        function RatioChangeButtonPushed(app, event)
            app.seg_len = app.SegmentlensEditField.Value;
            segs = app.start_t:app.seg_len:app.end_t;
            app.nrem_ratio_seg = [];
            app.rem_ratio_seg = [];
            app.wake_ratio_seg = [];
            start_idx = find(app.t >= app.start_t,1);
            for i = 1:length(segs)-1
                start_seg = find(app.t >= segs(i),1);
                end_seg = min([find(app.t >= segs(i+1),1)-1, length(app.t)]);
                app.nrem_ratio_seg(i) = length(find(app.nrem >= start_seg-start_idx+1 & app.nrem < end_seg-start_idx+1))/(end_seg-start_seg);
                app.rem_ratio_seg(i) = length(find(app.rem >= start_seg-start_idx+1 & app.rem < end_seg-start_idx+1))/(end_seg-start_seg);
                app.wake_ratio_seg(i) = length(find(app.wake >= start_seg-start_idx+1 & app.wake < end_seg-start_idx+1))/(end_seg-start_seg);
            end
            ax = app.RatioUIAxes;
            cla(ax);
            hold(ax,"on")
            plot(ax,1:length(segs)-1,app.nrem_ratio_seg,"Color",app.nrem_color,'LineWidth',2);
            plot(ax,1:length(segs)-1,app.rem_ratio_seg,"Color",app.rem_color,'LineWidth',2);
            plot(ax,1:length(segs)-1,app.wake_ratio_seg,"Color",app.wake_color,'LineWidth',2);
            ylim(ax,[0,1]);
            if length(segs)>1
            xlim(ax,[1,length(segs)-1]);
            end
        end

        % Button pushed function: LoadTriggersButton
        function LoadTriggersButtonPushed(app, event)
             [file,app.path] = uigetfile('*.continuous','Select Trigger signal file',app.path);
            if ~isequal(file,0)
                [app.triggers, ~, ~] = load_open_ephys_data(fullfile(app.path,file));   
                cla(app.TriggerUIAxes);
                plot(app.TriggerUIAxes, app.ts, app.triggers,'k');
                start_view = app.start_view_EditField.Value;
                end_view = app.end_view_EditField.Value;
                xlim(app.TriggerUIAxes,[start_view,end_view]);
                
                threshold = (max(app.triggers)+min(app.triggers))/2;
                app.TTL = app.triggers >= threshold;
                idx1 = find(app.TTL==1);
                count1 = 0;
                count2 = 0;
                app.start_trg =[];
                app.end_trg = [];
                for i = 1:length(idx1)
                    if (idx1(i) >= app.fs) && (idx1(i) <= length(app.TTL)-app.fs)
                    if mean(app.triggers(idx1(i)-app.fs:idx1(i)-1)) < 0.1
                        count1 = count1 + 1;
                        app.start_trg(count1) = idx1(i);
                    elseif mean(app.triggers(idx1(i)+1:idx1(i)+app.fs)) < 0.1
                        count2 = count2 + 1;
                        app.end_trg(count2) = idx1(i);
                    end
                    end
                end
                app.sel_range_off = 1:app.start_trg(1)-1;
                app.sel_range_on = [];
                for i = 1:count1-1
                    app.sel_range_on = [app.sel_range_on,app.start_trg(i):app.end_trg(i)];
                    app.sel_range_off = [app.sel_range_off, app.end_trg(i)+1:app.start_trg(i+1)-1];
                end
                app.sel_range_on = [app.sel_range_on,app.start_trg(count1):app.end_trg(count1)];
                app.sel_range_off = [app.sel_range_off, app.end_trg(count1):length(app.TTL)];
                hold(app.TriggerUIAxes,'on');
                plot(app.TriggerUIAxes,app.ts(app.sel_range_on),max(app.triggers)*1.1*ones(1,length(app.sel_range_on)),'r','LineWidth',2);                
            end
        end

        % Button pushed function: LoadPreprocessedButton
        function LoadPreprocessedButtonPushed(app, event)
             [app.prepro_file,app.path] = uigetfile('*.mat','Select Pre-processed file',app.path);
             prepro = load(fullfile(app.path, app.prepro_file));
             fields = fieldnames(prepro);
             for fi = 1:length(fields)
                 thisprop =fields{fi};
                 app.(thisprop) = prepro.(thisprop);
             end          
             plot(app.EEGUIAxes, app.ts, app.EEG,'k');
             xlim(app.EEGUIAxes,[0,app.maxt]);
             plot(app.EMGUIAxes, app.ts, app.EMG,'k');
             xlim(app.EMGUIAxes,[0,app.maxt]);
             %%% to be finished
             app.start_view_EditField.Value = app.start_t;
             app.end_view_EditField.Value = app.end_t;
             app.StartsEditField.Value = app.start_t;
             app.EndsEditField.Value = app.end_t;
             
             imagesc(app.spectrogramUIAxes, app.t,app.f,app.ps_db);
             caxis(app.spectrogramUIAxes,[-2,30]);
             axis(app.spectrogramUIAxes,"xy");
             colormap(app.spectrogramUIAxes, jet);
             xlim(app.spectrogramUIAxes,[app.start_t,app.end_t]);
                
            plot(app.deltaUIAxes, app.t, app.delta_power,'k');
            xlim(app.deltaUIAxes,[app.start_t,app.end_t]);
            plot(app.thetaratioUIAxes, app.t, app.theta_delta_ratio,'k');
            xlim(app.thetaratioUIAxes,[app.start_t,app.end_t]);    
            plot(app.EMGpowerUIAxes, app.t_emg, app.EMG_power,'k');
            xlim(app.EMGpowerUIAxes,[app.start_t,app.end_t]);
                                   
            sel_range = find(app.t >= app.start_t & app.t <= app.end_t);
            dt = app.t(2)-app.t(1);
            total_len = length(sel_range);
            
            hold(app.EMGpowerUIAxes,'on');
            hold(app.deltaUIAxes,'on');
            hold(app.thetaratioUIAxes,'on');
            
            [start_nrem,end_nrem,lens_nrem]=find_bound(app.nrem);
            start_nrem=app.nrem(start_nrem);
            end_nrem=app.nrem(end_nrem);
            [start_rem,end_rem,lens_rem]=find_bound(app.rem);
            start_rem=app.rem(start_rem);
            end_rem=app.rem(end_rem);
            [start_wake,end_wake,lens_wake]=find_bound(app.wake);
            start_wake=app.wake(start_wake);
            end_wake=app.wake(end_wake);
            
            marker_alpha=0.3;
            
            marker_range=[min(app.EMG_power(sel_range) ),max(app.EMG_power(sel_range) )];
            plot_index(app, app.EMGpowerUIAxes,start_nrem, end_nrem,app.nrem_color,marker_alpha, marker_range)
            plot_index(app, app.EMGpowerUIAxes,start_rem, end_rem,app.rem_color,marker_alpha, marker_range)
            plot_index(app, app.EMGpowerUIAxes,start_wake, end_wake,app.wake_color,marker_alpha, marker_range)
            
            marker_range=[min(app.delta_power(sel_range) ),max(app.delta_power(sel_range) )];
            plot_index(app, app.deltaUIAxes,start_nrem, end_nrem,app.nrem_color,marker_alpha, marker_range)
            plot_index(app, app.deltaUIAxes,start_rem, end_rem,app.rem_color,marker_alpha, marker_range)
            plot_index(app, app.deltaUIAxes,start_wake, end_wake,app.wake_color,marker_alpha, marker_range)
            
            marker_range=[min(app.theta_delta_ratio(sel_range) ),max(app.theta_delta_ratio(sel_range) )];
            plot_index(app, app.thetaratioUIAxes,start_nrem, end_nrem,app.nrem_color,marker_alpha, marker_range)
            plot_index(app, app.thetaratioUIAxes,start_rem, end_rem,app.rem_color,marker_alpha, marker_range)
            plot_index(app, app.thetaratioUIAxes,start_wake, end_wake,app.wake_color,marker_alpha, marker_range)
            
            EMG_above = find(app.EMG_power(sel_range) > app.EMG_power_thre);
            EMG_below = setdiff(1:total_len,EMG_above);
            plot(app.EMGpowerUIAxes, app.t_emg(sel_range(EMG_above)),app.EMG_power(sel_range(EMG_above)),'r.');
            plot(app.EMGpowerUIAxes, app.t_emg(sel_range(EMG_below)),app.EMG_power(sel_range(EMG_below)),'b.');
            delta_above = find(app.delta_power(sel_range) > app.delta_thre);
            delta_below = setdiff(1:total_len,delta_above);
            plot(app.deltaUIAxes, app.t(sel_range(delta_above)),app.delta_power(sel_range(delta_above)),'r.');
            plot(app.deltaUIAxes, app.t(sel_range(delta_below)),app.delta_power(sel_range(delta_below)),'b.');
            
            theta_ratio_above = find(app.theta_delta_ratio(sel_range) > app.theta_delta_ratio_thre);
            theta_ratio_below = setdiff(1:total_len,theta_ratio_above);
            plot(app.thetaratioUIAxes, app.t(sel_range(theta_ratio_above)),app.theta_delta_ratio(sel_range(theta_ratio_above)),'r.');
            plot(app.thetaratioUIAxes, app.t(sel_range(theta_ratio_below)),app.theta_delta_ratio(sel_range(theta_ratio_below)),'b.');
            
            hold(app.EMGpowerUIAxes,'off');
            hold(app.deltaUIAxes,'off');
            hold(app.thetaratioUIAxes,'off');            
            
            app.nremLabel.Visible ='on';
            app.nremLabel.Text=['NREM: ',num2str(length(app.nrem)*dt),' s (',num2str(app.nrem_ratio*100,2),' %)'];
            app.nremLabel.FontColor = app.nrem_color;
            
            app.remLabel.Visible ='on';
            app.remLabel.Text=['REM: ',num2str(length(app.rem)*dt),' s (',num2str(app.rem_ratio*100,2),' %)'];
            app.remLabel.FontColor = app.rem_color;
            
            app.wakeLabel.Visible ='on';
            app.wakeLabel.Text=['WAKE: ',num2str(length(app.wake)*dt),' s (',num2str(app.wake_ratio*100,2),' %)'];
            app.wakeLabel.FontColor = app.wake_color;
            
            ax1 = app.DeltaThetaUIAxes;
            cla(ax1);
            hold(ax1,"on")
            plot(ax1, app.delta_power(app.nrem), app.theta_delta_ratio(app.nrem),'.','Color',app.nrem_color);
            plot(ax1, app.delta_power(app.rem), app.theta_delta_ratio(app.rem),'.','Color',app.rem_color);
            plot(ax1, app.delta_power(app.wake), app.theta_delta_ratio(app.wake),'.','Color',app.wake_color);
            app.l_delta = drawline(ax1,'Position',[app.delta_thre, min(app.theta_delta_ratio);app.delta_thre,max(app.theta_delta_ratio)],'Color','r');
            app.l_theta = drawline(ax1,'Position',[min(app.delta_power), app.theta_delta_ratio_thre;max(app.delta_power),app.theta_delta_ratio_thre],'Color','r');
            addlistener(app.l_delta,'ROIMoved',@app.delta_events);
            addlistener(app.l_theta,'ROIMoved',@app.theta_events);
            xlim(app.DeltaThetaUIAxes,[app.delta_from, app.delta_to]);
            ylim(app.DeltaThetaUIAxes,[app.theta_from, app.theta_to]);
                        
            ax12 = app.DeltaDistUIAxes;
            hold(ax12,"on")
            delete(app.l_delta_dist)
            app.l_delta_dist = drawline(ax12,'Position',[app.delta_thre_tmp, 0;app.delta_thre_tmp,max(app.n_delta)],'Color','r');
            addlistener(app.l_delta_dist,'ROIMoved',@app.delta_dist_events);
            xlim(app.DeltaDistUIAxes,[app.delta_from, app.delta_to]);
            
            ax11 = app.ThetaDistUIAxes;
            hold(ax11,"on")
            delete(app.l_theta_dist);
            app.l_theta_dist = drawline(ax11,'Position',[0 app.theta_thre_tmp;max(app.n_theta) app.theta_thre_tmp],'Color','r');
            addlistener(app.l_theta_dist,'ROIMoved',@app.theta_dist_events);
            ylim(app.ThetaDistUIAxes,[app.theta_from, app.theta_to]);
            
            ax2 = app.EMGThetaUIAxes;
            cla(ax2);
            hold(ax2,"on")
            plot(ax2, app.EMG_power(app.nrem), app.theta_delta_ratio(app.nrem),'.','Color',app.nrem_color);
            plot(ax2, app.EMG_power(app.rem), app.theta_delta_ratio(app.rem),'.','Color',app.rem_color);
            plot(ax2, app.EMG_power(app.wake), app.theta_delta_ratio(app.wake),'.','Color',app.wake_color);
            app.l_EMG = drawline(ax2,'Position',[app.EMG_thre_tmp, min(app.theta_delta_ratio);app.EMG_thre_tmp,max(app.theta_delta_ratio)],'Color','r');
            app.l_theta_2 = drawline(ax2,'Position',[min(app.EMG_power), app.theta_thre_tmp;max(app.EMG_power),app.theta_thre_tmp],'Color','r');
            addlistener(app.l_EMG,'ROIMoved',@app.EMG_events);
            addlistener(app.l_theta_2,'ROIMoved',@app.theta_2_events);
            xlim(app.EMGThetaUIAxes,[app.EMG_from, app.EMG_to]);
            ylim(app.EMGThetaUIAxes,[app.theta_from, app.theta_to]);
            
            ax22 = app.EMGDistUIAxes;
            hold(ax22,"on")
            delete(app.l_EMG_dist)
            app.l_EMG_dist = drawline(ax22,'Position',[app.EMG_thre_tmp, 0;app.EMG_thre_tmp,max(app.n_EMG)],'Color','r');
            addlistener(app.l_EMG_dist,'ROIMoved',@app.EMG_dist_events);
            xlim(app.EMGDistUIAxes,[app.EMG_from, app.EMG_to]);
            
            cla(app.stateUIAxes);
            plot(app.stateUIAxes,app.state_x, app.state_code,'k');
            app.stateUIAxes.YTick=0:2;
            app.stateUIAxes.YTickLabel={"W",'N','R'};
            xlim(app.stateUIAxes,[app.start_t,app.end_t]);
            ylim(app.stateUIAxes, [-1,3]);
            
            app.SegmentlensEditField.Value = app.seg_len;
            segs = 0:app.seg_len:app.end_t-app.start_t;
            ax = app.RatioUIAxes;
            cla(ax);
            hold(ax,"on")
            plot(ax,1:length(segs)-1,app.nrem_ratio_seg,"Color",app.nrem_color,'LineWidth',2);
            plot(ax,1:length(segs)-1,app.rem_ratio_seg,"Color",app.rem_color,'LineWidth',2);
            plot(ax,1:length(segs)-1,app.wake_ratio_seg,"Color",app.wake_color,'LineWidth',2);
            ylim(ax,[0,1]);
            xlim(ax,[1,length(segs)-1]);
        end

        % Button pushed function: ResetThetaButton
        function ResetThetaButtonPushed(app, event)
            app.theta_from_EditField.Value = min(app.theta_x);
            app.theta_to_EditField.Value = max(app.theta_x);
            app.theta_from = app.theta_from_EditField.Value;
            app.theta_to = app.theta_to_EditField.Value;
            ylim(app.DeltaThetaUIAxes,[app.theta_from,app.theta_to]);
            ylim(app.ThetaDistUIAxes,[app.theta_from,app.theta_to]);
            ylim(app.EMGThetaUIAxes,[app.theta_from,app.theta_to]);
        end

        % Button pushed function: ResetDeltaButton
        function ResetDeltaButtonPushed(app, event)
            app.delta_from_EditField.Value = min(app.delta_x);
            app.delta_to_EditField.Value = max(app.delta_x);
            app.delta_from = app.delta_from_EditField.Value;
            app.delta_to = app.delta_to_EditField.Value;
            xlim(app.DeltaThetaUIAxes,[app.delta_from,app.delta_to]);
            xlim(app.DeltaDistUIAxes,[app.delta_from,app.delta_to]);
        end

        % Button pushed function: ResetEMGButton
        function ResetEMGButtonPushed(app, event)
            app.EMG_from_EditField.Value = min(app.EMG_x);
            app.EMG_to_EditField.Value = max(app.EMG_x);
            app.EMG_from = app.EMG_from_EditField.Value;
            app.EMG_to = app.EMG_to_EditField.Value;
            xlim(app.EMGThetaUIAxes,[app.EMG_from,app.EMG_to]);
            xlim(app.EMGDistUIAxes,[app.EMG_from,app.EMG_to]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadEEGButton = uibutton(app.UIFigure, 'push');
            app.LoadEEGButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEEGButtonPushed, true);
            app.LoadEEGButton.Text = 'Load EEG';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.fsHzEditFieldLabel = uilabel(app.UIFigure);
            app.fsHzEditFieldLabel.Text = 'fs(Hz)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '--';
            app.end_view_EditField = uieditfield(app.UIFigure, 'numeric');
            app.end_view_EditField.ValueChangedFcn = createCallbackFcn(app, @end_view_EditFieldValueChanged, true);
            app.end_view_EditField.Value = 60;
            app.fsHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.fsHzEditField.ValueChangedFcn = createCallbackFcn(app, @fsHzEditFieldValueChanged, true);
            app.fsHzEditField.Value = 1000;
            app.viewEditFieldLabel = uilabel(app.UIFigure);
            app.viewEditFieldLabel.Text = 'view';
            app.start_view_EditField = uieditfield(app.UIFigure, 'numeric');
            app.start_view_EditField.ValueChangedFcn = createCallbackFcn(app, @start_view_EditFieldValueChanged, true);
            app.LoadEMGButton = uibutton(app.UIFigure, 'push');
            app.LoadEMGButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEMGButtonPushed, true);
            app.LoadEMGButton.Text = 'Load EMG';
            app.FullViewButton = uibutton(app.UIFigure, 'push');
            app.FullViewButton.ButtonPushedFcn = createCallbackFcn(app, @FullViewButtonPushed, true);
            app.FullViewButton.Text = 'Full View';
            app.EEGSpectrogramButton = uibutton(app.UIFigure, 'push');
            app.EEGSpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @EEGSpectrogramButtonPushed, true);
            app.EEGSpectrogramButton.Text = 'EEG Spectrogram';
            app.WinLensEditFieldLabel = uilabel(app.UIFigure);
            app.WinLensEditFieldLabel.Text = 'Win Len(s)';
            app.WinLensEditField = uieditfield(app.UIFigure, 'numeric');
            app.WinLensEditField.Limits = [0 Inf];
            app.WinLensEditField.Value = 5;
            app.OverlapsEditFieldLabel = uilabel(app.UIFigure);
            app.OverlapsEditFieldLabel.Text = 'Overlap(s)';
            app.OverlapsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapsEditField.Limits = [0 Inf];
            app.OverlapsEditField.Value = 2.5;
            app.SelROICheckBox = uicheckbox(app.UIFigure);
            app.SelROICheckBox.Text = 'Sel ROI';
            app.EpochEditFieldLabel = uilabel(app.UIFigure);
            app.EpochEditFieldLabel.Text = 'Epoch';
            app.EpochEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpochEditField.Value = 4;
            app.sLabel_5 = uilabel(app.UIFigure);
            app.sLabel_5.Text = 's';
            app.AutoScoreButton = uibutton(app.UIFigure, 'push');
            app.AutoScoreButton.ButtonPushedFcn = createCallbackFcn(app, @AutoScoreButtonPushed, true);
            app.AutoScoreButton.Text = 'Auto Score';
            app.ManualThresholdButton = uibutton(app.UIFigure, 'push');
            app.ManualThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ManualThresholdButtonPushed, true);
            app.ManualThresholdButton.Text = 'Manual Threshold';
            app.nremLabel = uilabel(app.UIFigure);
            app.nremLabel.Text = ' ';
            app.remLabel = uilabel(app.UIFigure);
            app.remLabel.Text = ' ';
            app.wakeLabel = uilabel(app.UIFigure);
            app.wakeLabel.Text = ' ';
            app.StartsEditFieldLabel = uilabel(app.UIFigure);
            app.StartsEditFieldLabel.Text = 'Start(s)';
            app.StartsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndsEditFieldLabel = uilabel(app.UIFigure);
            app.EndsEditFieldLabel.Text = 'End(s)';
            app.EndsEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndsEditField.Value = 1;
            app.binsEditFieldLabel = uilabel(app.UIFigure);
            app.binsEditFieldLabel.Text = 'bins';
            app.bins_delta_EditField = uieditfield(app.UIFigure, 'numeric');
            app.bins_delta_EditField.ValueChangedFcn = createCallbackFcn(app, @bins_delta_EditFieldValueChanged, true);
            app.bins_delta_EditField.Value = 100;
            app.binsEditField_2Label = uilabel(app.UIFigure);
            app.binsEditField_2Label.Text = 'bins';
            app.bins_theta_EditField = uieditfield(app.UIFigure, 'numeric');
            app.bins_theta_EditField.ValueChangedFcn = createCallbackFcn(app, @bins_theta_EditFieldValueChanged, true);
            app.bins_theta_EditField.Value = 100;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '--';
            app.delta_to_EditField = uieditfield(app.UIFigure, 'numeric');
            app.delta_to_EditField.ValueChangedFcn = createCallbackFcn(app, @delta_to_EditFieldValueChanged, true);
            app.delta_to_EditField.Value = 1;
            app.binsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.binsEditFieldLabel_2.Text = 'bins';
            app.bins_EMG_EditField = uieditfield(app.UIFigure, 'numeric');
            app.bins_EMG_EditField.ValueChangedFcn = createCallbackFcn(app, @bins_EMG_EditFieldValueChanged, true);
            app.bins_EMG_EditField.Value = 100;
            app.EMG_from_EditField = uieditfield(app.UIFigure, 'numeric');
            app.EMG_from_EditField.ValueChangedFcn = createCallbackFcn(app, @EMG_from_EditFieldValueChanged, true);
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '--';
            app.EMG_to_EditField = uieditfield(app.UIFigure, 'numeric');
            app.EMG_to_EditField.ValueChangedFcn = createCallbackFcn(app, @EMG_to_EditFieldValueChanged, true);
            app.EMG_to_EditField.Value = 1;
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.theta_from_EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta_from_EditField.ValueChangedFcn = createCallbackFcn(app, @theta_from_EditFieldValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '--';
            app.delta_from_EditField = uieditfield(app.UIFigure, 'numeric');
            app.delta_from_EditField.ValueChangedFcn = createCallbackFcn(app, @delta_from_EditFieldValueChanged, true);
            app.theta_to_EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta_to_EditField.ValueChangedFcn = createCallbackFcn(app, @theta_to_EditFieldValueChanged, true);
            app.theta_to_EditField.Value = 1;
            app.NarrowDownButton = uibutton(app.UIFigure, 'push');
            app.NarrowDownButton.ButtonPushedFcn = createCallbackFcn(app, @NarrowDownButtonPushed, true);
            app.NarrowDownButton.Text = 'Narrow Down';
            app.SegmentlensEditFieldLabel = uilabel(app.UIFigure);
            app.SegmentlensEditFieldLabel.Text = 'Segment len(s)';
            app.SegmentlensEditField = uieditfield(app.UIFigure, 'numeric');
            app.SegmentlensEditField.Value = 60;
            app.RatioChangeButton = uibutton(app.UIFigure, 'push');
            app.RatioChangeButton.ButtonPushedFcn = createCallbackFcn(app, @RatioChangeButtonPushed, true);
            app.RatioChangeButton.Text = 'Ratio Change';
            app.LoadTriggersButton = uibutton(app.UIFigure, 'push');
            app.LoadTriggersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTriggersButtonPushed, true);
            app.LoadTriggersButton.Text = 'Load Triggers';
            app.LoadPreprocessedButton = uibutton(app.UIFigure, 'push');
            app.LoadPreprocessedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreprocessedButtonPushed, true);
            app.LoadPreprocessedButton.Text = 'Load Preprocessed';
            app.ResetThetaButton = uibutton(app.UIFigure, 'push');
            app.ResetThetaButton.ButtonPushedFcn = createCallbackFcn(app, @ResetThetaButtonPushed, true);
            app.ResetThetaButton.Text = 'Reset';
            app.ResetDeltaButton = uibutton(app.UIFigure, 'push');
            app.ResetDeltaButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDeltaButtonPushed, true);
            app.ResetDeltaButton.Text = 'Reset';
            app.ResetEMGButton = uibutton(app.UIFigure, 'push');
            app.ResetEMGButton.ButtonPushedFcn = createCallbackFcn(app, @ResetEMGButtonPushed, true);
            app.ResetEMGButton.Text = 'Reset';
            app.EEGUIAxes = uiaxes(app.UIFigure);
            title(app.EEGUIAxes, 'EEG')
            ylabel(app.EEGUIAxes, 'uV')
            zlabel(app.EEGUIAxes, 'Z')
            app.EMGUIAxes = uiaxes(app.UIFigure);
            title(app.EMGUIAxes, 'EMG')
            ylabel(app.EMGUIAxes, 'uV')
            zlabel(app.EMGUIAxes, 'Z')
            app.spectrogramUIAxes = uiaxes(app.UIFigure);
            title(app.spectrogramUIAxes, 'EEG Spectrogram')
            ylabel(app.spectrogramUIAxes, 'dB')
            zlabel(app.spectrogramUIAxes, 'Z')
            app.deltaUIAxes = uiaxes(app.UIFigure);
            title(app.deltaUIAxes, '\delta Power')
            ylabel(app.deltaUIAxes, 'uV^2')
            zlabel(app.deltaUIAxes, 'Z')
            app.deltaUIAxes.ButtonDownFcn = createCallbackFcn(app, @deltaUIAxesButtonDown, true);
            app.thetaratioUIAxes = uiaxes(app.UIFigure);
            title(app.thetaratioUIAxes, '\theta / \delta')
            ylabel(app.thetaratioUIAxes, 'ratio')
            zlabel(app.thetaratioUIAxes, 'Z')
            app.EMGpowerUIAxes = uiaxes(app.UIFigure);
            title(app.EMGpowerUIAxes, 'EMG Power')
            xlabel(app.EMGpowerUIAxes, 'Time (s)')
            ylabel(app.EMGpowerUIAxes, 'uV^2')
            zlabel(app.EMGpowerUIAxes, 'Z')
            app.DeltaThetaUIAxes = uiaxes(app.UIFigure);
            zlabel(app.DeltaThetaUIAxes, 'Z')
            app.ThetaDistUIAxes = uiaxes(app.UIFigure);
            ylabel(app.ThetaDistUIAxes, '\theta / \delta')
            zlabel(app.ThetaDistUIAxes, 'Z')
            app.DeltaDistUIAxes = uiaxes(app.UIFigure);
            xlabel(app.DeltaDistUIAxes, 'Delta Power')
            zlabel(app.DeltaDistUIAxes, 'Z')
            app.EMGThetaUIAxes = uiaxes(app.UIFigure);
            zlabel(app.EMGThetaUIAxes, 'Z')
            app.EMGDistUIAxes = uiaxes(app.UIFigure);
            xlabel(app.EMGDistUIAxes, 'EMG Power')
            zlabel(app.EMGDistUIAxes, 'Z')
            app.stateUIAxes = uiaxes(app.UIFigure);
            zlabel(app.stateUIAxes, 'Z')
            app.stateUIAxes.YTick = [1 2 3];
            app.stateUIAxes.YTickLabel = {'"W"'; '"N"'; '"R"'};
            app.RatioUIAxes = uiaxes(app.UIFigure);
            xlabel(app.RatioUIAxes, 'Segment #')
            ylabel(app.RatioUIAxes, 'Ratio')
            zlabel(app.RatioUIAxes, 'Z')
            app.TriggerUIAxes = uiaxes(app.UIFigure);
            title(app.TriggerUIAxes, {''; ''})
            ylabel(app.TriggerUIAxes, 'Trigger')
            zlabel(app.TriggerUIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sleep_ana

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end